classdef GenderRecognitionAppGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        StartRecordingButton          matlab.ui.control.Button
        StopRecordingButton           matlab.ui.control.Button
        ImportRecordingButton         matlab.ui.control.Button
        CreateGraphButton             matlab.ui.control.Button
        CalculateButton               matlab.ui.control.Button
        PredictGenderButton           matlab.ui.control.Button
        RecordingLampLabel            matlab.ui.control.Label
        RecordingLamp                 matlab.ui.control.Lamp
        GenderPredictionbyVoiceLabel  matlab.ui.control.Label
        CalculatedPropertiesLabel     matlab.ui.control.Label
        PredictedGenderLabel          matlab.ui.control.Label
        GenderLabel                   matlab.ui.control.Label
        PlayRecordingButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        recorder; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            global recObj
               recObj = audiorecorder ; %create object
               record(recObj); %start Recording
               app.RecordingLampLabel.Text = 'Recording';
%             app.recorder = audiorecorder
%             record(app.recorder,3)
%             disp('Start speaking.')
%             % app.recorder.StartFcn();
            %https://www.mathworks.com/help/matlab/ref/audiorecorder.html
        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
            global recObj
                stop(recObj) % Stop
                app.RecordingLampLabel.Text = 'Stop Recording';
%             stop(app.recorder);
%             disp('End of Recording.');
        end

        % Button pushed function: ImportRecordingButton
        function ImportRecordingButtonPushed(app, event)

        end

        % Button pushed function: PlayRecordingButton
        function PlayRecordingButtonPushed(app, event)
             global recObj
                y=getaudiodata(recObj);
                plot(app.UIAxes,y)
                play(recObj) % Play
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voice Sample')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Icon = 'baseline_fiber_manual_record_black_18dp.png';
            app.StartRecordingButton.Text = {'Start '; 'Recording'};
            app.StopRecordingButton = uibutton(app.UIFigure, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Icon = 'baseline_stop_black_18dp.png';
            app.StopRecordingButton.Text = {'Stop '; 'Recording'};
            app.ImportRecordingButton = uibutton(app.UIFigure, 'push');
            app.ImportRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @ImportRecordingButtonPushed, true);
            app.ImportRecordingButton.Icon = 'baseline_save_alt_black_18dp.png';
            app.ImportRecordingButton.Text = {'Import'; 'Recording'};
            app.CreateGraphButton = uibutton(app.UIFigure, 'push');
            app.CreateGraphButton.Icon = 'baseline_graphic_eq_black_18dp.png';
            app.CreateGraphButton.Text = {'Create '; 'Graph'};
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.Icon = 'baseline_calculate_black_18dp.png';
            app.CalculateButton.Text = 'Calculate';
            app.PredictGenderButton = uibutton(app.UIFigure, 'push');
            app.PredictGenderButton.Icon = 'baseline_supervised_user_circle_black_18dp.png';
            app.PredictGenderButton.Text = {'Predict'; 'Gender'};
            app.RecordingLampLabel = uilabel(app.UIFigure);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.UIFigure);
            app.GenderPredictionbyVoiceLabel = uilabel(app.UIFigure);
            app.GenderPredictionbyVoiceLabel.Text = 'Gender Prediction by Voice';
            app.CalculatedPropertiesLabel = uilabel(app.UIFigure);
            app.CalculatedPropertiesLabel.Text = 'Calculated Properties';
            app.PredictedGenderLabel = uilabel(app.UIFigure);
            app.PredictedGenderLabel.Text = 'Predicted Gender';
            app.GenderLabel = uilabel(app.UIFigure);
            app.GenderLabel.Text = 'GenderLabel';
            app.PlayRecordingButton = uibutton(app.UIFigure, 'push');
            app.PlayRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRecordingButtonPushed, true);
            app.PlayRecordingButton.Text = {'Play '; 'Recording'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GenderRecognitionAppGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end